classdef Rummy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RummyUIFigure                  matlab.ui.Figure
        OpponentLabel                  matlab.ui.control.Label
        OpponentPile                   matlab.ui.control.Button
        AnimationCard_2                matlab.ui.control.Image
        settingsButton                 matlab.ui.control.Button
        AnimationCard                  matlab.ui.control.Image
        WaitLamp                       matlab.ui.control.Lamp
        WaitingtowriteThingspeakLabel  matlab.ui.control.Label
        QueryTurnButton                matlab.ui.control.Button
        PlayerIndicatorLabel           matlab.ui.control.Label
        guideButton                    matlab.ui.control.Button
        LeaderboardTitle               matlab.ui.control.Image
        DiscardPileButton              matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        Card8Button                    matlab.ui.control.StateButton
        Card7Button                    matlab.ui.control.StateButton
        Card6Button                    matlab.ui.control.StateButton
        Card5Button                    matlab.ui.control.StateButton
        Card1Button                    matlab.ui.control.StateButton
        Card3Button                    matlab.ui.control.StateButton
        Card2Button                    matlab.ui.control.StateButton
        Card4Button                    matlab.ui.control.StateButton
        WinButton                      matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        SortBySuitButton               matlab.ui.control.Button
        SortByHandButton               matlab.ui.control.Button
        DiscardButton                  matlab.ui.control.Button
        CurrentPhaseLabel              matlab.ui.control.Label
        DrawPileButton                 matlab.ui.control.Button
        DiscardPileLabel               matlab.ui.control.Label
        DrawPileLabel                  matlab.ui.control.Label
        TurnLabel                      matlab.ui.control.Label
        backgroundImage                matlab.ui.control.Image
    end


    properties (Access = private)
        player1Hand;
        player2Hand;
        drawPile;
        discardPile;

        currentPlayerNum = 1;

        currentTurnPhase = "draw"

        winnerList;
        currentFilePath = fileparts(mfilename("fullpath"));

        HandCardButtons;

        guide;

        channelID = 2570740;
        writeKey = "LP52VYH6OR502LC0";
        readKey = "TYUSYKJEQ5E9E2VM";

        myPlayerNum = 1;

        online = true;

        drawChoice
        discardChoice

        musicPlayer = audioplayer(0, 80);

        currentTheme = "none"

    end

    properties (Access = private, Dependent)
        currentPlayerHand
        selectedCardIndex
    end

    methods (Access = private)
        %% UI Changes
        % Graphics

        function updateHandDisplay(app)
            cardsList = fullfile(app.currentFilePath,...
                app.currentPlayerHand.filenames);
            if length(cardsList) == 7
                cardsList(8) = fullfile(app.currentFilePath,...
                    "images", "misc", "cardPlacehold.png");
            end
            for i = 1:8
                app.HandCardButtons(i).Icon = cardsList(i);
            end

        end

        function updateTopCard(app)
            app.DiscardPileButton.Icon = fullfile(app.currentFilePath,...
                app.discardPile.topCardFile);
        end

        function animateCard(app, startPos, endPos, cardFile)
            app.AnimationCard.Visible = "on";
            app.AnimationCard.ImageSource = cardFile;
            numFrames = 50;
            duration = 0.5;
            for t = linspace(0, 1, numFrames)
                pause(duration/numFrames);
                app.AnimationCard.Position(1:2) = startPos*(1-t) + endPos*t;
            end
            app.AnimationCard.Visible = "off";
        end

        function drawCardAnimation(app, pile)
            app.DrawPileButton.Enable = "off";
            app.DiscardPileButton.Enable = "off";
            switch pile
                case "draw"
                    startPos = app.DrawPileButton.Position(1:2);
                case "discard"
                    startPos = app.DiscardPileButton.Position(1:2);
            end
            endPos = app.Panel.Position(1:2) + app.Card8Button.Position(1:2);
            cardFile = fullfile(app.currentFilePath, app.currentPlayerHand.lastCardFile);
            app.updateTopCard;
            app.animateCard(startPos, endPos, cardFile);
            app.updateHandDisplay;
        end

        function discardAnimation(app, cardIndex)
            app.DiscardButton.Enable = "off";
            startPos = app.HandCardButtons(cardIndex).Position(1:2) + ...
                app.Panel.Position(1:2);
            endPos = app.DiscardPileButton.Position(1:2);
            cardFile = fullfile(app.currentFilePath, app.discardPile.topCardFile);
            app.HandCardButtons(cardIndex).Icon = fullfile(app.currentFilePath,...
                "images", "misc", "cardPlacehold.png");
            app.animateCard(startPos, endPos, cardFile);
            app.updateHandDisplay;
            app.updateTopCard;
        end

        function swapCardsAnimation(app, index1, index2)
            position1 = app.HandCardButtons(index1).Position(1:2) +...
                app.Panel.Position(1:2);
            position2 = app.HandCardButtons(index2).Position(1:2) +...
                app.Panel.Position(1:2);
            cardFile1 = fullfile(app.currentFilePath,...
                app.currentPlayerHand.fileFromIndex(index1));
            cardFile2 = fullfile(app.currentFilePath,...
                app.currentPlayerHand.fileFromIndex(index2));
            app.AnimationCard.ImageSource = cardFile1;
            app.AnimationCard_2.ImageSource = cardFile2;
            placeholderFile = fullfile(app.currentFilePath,...
                "images", "misc", "cardPlacehold.png");
            set(app.HandCardButtons([index1, index2]), 'Icon', placeholderFile);
            numFrames = 50;
            duration = 0.35;
            app.AnimationCard.Visible = "on";
            app.AnimationCard_2.Visible = "on";
            for t = linspace(0, 1, numFrames)
                pause(duration/numFrames);
                app.AnimationCard.Position(1:2) = position1*(1-t) + position2*t;
                app.AnimationCard_2.Position(1:2) = position2*(1-t) + position1*t;
            end
            app.AnimationCard.Visible = "off";
            app.AnimationCard_2.Visible = "off";
        end

        function opponentTurnAnimation(app, drawChoice, discardChoice)
            % draw phase
            if drawChoice == 0
                startPos = app.DrawPileButton.Position(1:2);
                cardFile = fullfile(app.currentFilePath, "images", "misc", "cardBack.png");
            else
                app.DiscardPileButton.Icon = fullfile(app.currentFilePath, app.discardPile.secondCardFile);
                startPos = app.DiscardPileButton.Position(1:2);
                cardArraySample = CardArray(drawChoice);
                cardFile = fullfile(app.currentFilePath, cardArraySample.filenames);
            end
            endPos = app.OpponentPile.Position(1:2);
            app.animateCard(startPos, endPos, cardFile);

            % discard phase
            startPos = app.OpponentPile.Position(1:2);
            endPos = app.DiscardPileButton.Position(1:2);
            cardArraySample = CardArray(discardChoice);
            cardFile = fullfile(app.currentFilePath, cardArraySample.filenames);
            app.animateCard(startPos, endPos, cardFile);
        end

        function endTurnAnimation(app)
            duration = 0.5;
            cardBackFile = fullfile(app.currentFilePath, "images", "misc", "cardBack.png");
            for i = 1:7
                pause(duration/7);
                app.HandCardButtons(i).Icon = cardBackFile;
            end
        end
        function startTurnAnimation(app)
            duration = 0.5;
            cardFiles = fullfile(app.currentFilePath, app.currentPlayerHand.filenames);
            for i = 1:7
                pause(duration/7);
                app.HandCardButtons(i).Icon = cardFiles(i);
            end
        end


        function updateTurnPhase(app, newPhase)
            switch newPhase
                case "draw"
                    app.CurrentPhaseLabel.Text = "Draw a Card from one of the piles.";
                    app.DrawPileButton.Enable = "on";
                    app.DiscardPileButton.Enable = "on";
                    app.DiscardButton.Enable = "off";
                    if checkRummyHand(app.currentPlayerHand.cards)
                        app.WinButton.Enable = "on";
                    end
                case "discard"
                    app.CurrentPhaseLabel.Text = "Choose a card to discard.";
                    app.DrawPileButton.Enable = "off";
                    app.DiscardPileButton.Enable = "off";
                    app.WinButton.Enable = "off";
                    app.DiscardButton.Enable = "on";
            end
        end

        function updateTurnOnline(app)
            if app.currentPlayerNum == app.myPlayerNum
                app.TurnLabel.Text = "Your Turn";

                app.updateTurnPhase("draw");
            else
                app.TurnLabel.Text = "Player " + app.currentPlayerNum + "'s Turn";
                app.CurrentPhaseLabel.Text = "Wait for your turn.";
                app.DrawPileButton.Enable = "off";
                app.DiscardPileButton.Enable = "off";
                app.DiscardButton.Enable = "off";
                app.WinButton.Enable = "off";
                app.awaitNextTurn;
            end
        end
        function nextPhase(app)
            currentPhase = app.currentTurnPhase;
            app.updateTopCard
            switch currentPhase
                case "draw"
                    % Switch to Discard Phase
                    app.currentTurnPhase = "discard";
                    app.updateTurnPhase("discard")
                case "discard"
                    % Switch to Draw Phase
                    app.currentTurnPhase = "draw";
                    app.updateTurnPhase("draw")

                    % It must be time to switch turns
                    app.nextTurn
            end

        end


        function nextTurn(app)
            prevPlayerNum = app.currentPlayerNum;
            switch prevPlayerNum
                case 1
                    app.currentPlayerNum = 2;
                case 2
                    app.currentPlayerNum = 1;
            end
            if app.online
                app.updateTurnOnline;
            else
                app.TurnLabel.Text = "Player "+ app.currentPlayerNum...
                    + "'s Turn";
                app.endTurnAnimation;
                app.startTurnAnimation;
                app.updateHandDisplay;
            end

        end

        function awaitNextTurn(app)
            lastWriter = app.readThingSpeak(4);
            if ~(app.myPlayerNum == 2 && lastWriter == 3)
                app.writeTurnData;
            end
            waiting = true;
            while waiting
                pause(1);
                lastWriter = app.readThingSpeak(4);
                % Query whether the other player
                switch app.myPlayerNum
                    case 1
                        waiting = lastWriter ~= 2;
                    case 2
                        waiting = lastWriter ~= 1;
                end
            end
            app.readTurnData;
            app.nextTurn;
        end

        function readTurnData(app)
            winner = app.readThingSpeak(8);
            if winner
                app.win;
            end
            switch app.myPlayerNum
                case 1
                    fields = 5:6;
                    data = app.readThingSpeak(fields);
                    choiceOfDraw = data(1);
                    choiceOfDiscard = data(2);
                    switch choiceOfDraw
                        case 0
                            animateDrawChoice = 0;
                        otherwise
                            animateDrawChoice = app.discardPile.topCard;
                    end
                    app.opponentTurnAnimation(animateDrawChoice, choiceOfDiscard);
                    indexToDiscard = app.player2Hand.findCard(choiceOfDiscard);
                    app.player2Hand.DrawCard(choiceOfDraw);
                    app.player2Hand.discard(indexToDiscard);
                    app.updateTopCard;
                case 2
                    fields = [1:3, 5];
                    data = app.readThingSpeak(fields);
                    nextDraw = data(1);
                    Discard1 = data(2);
                    Discard2 = data(3);
                    opponentDrawChoice = data(4);
                    switch opponentDrawChoice
                        case 0
                            animateDrawChoice = 0;
                        otherwise
                            animateDrawChoice = app.discardPile.topCard;
                    end
                    choiceOfDiscard = Discard1;
                    app.opponentTurnAnimation(animateDrawChoice, choiceOfDiscard);                    app.drawPile.cards = nextDraw;
                    app.discardPile.cards = [Discard1, Discard2];
                    app.updateTopCard;
            end
        end

        function writeTurnData(app)
            switch app.myPlayerNum
                case 1
                    fields = [1:5,8];
                    nextDraw = app.drawPile.topCard;
                    Discard1 = app.discardPile.topCard;
                    Discard2 = app.discardPile.secondCard;
                    lastWriter = 1;
                    pileChoice = app.drawChoice;
                    data = [nextDraw, Discard1, Discard2, lastWriter, pileChoice, 0];
                    app.writeThingSpeak(fields, data);
                case 2
                    fields = [5,6,4,8];
                    pileChoice = app.drawChoice;
                    discardCard = app.discardChoice;
                    lastWriter = 2;
                    data = [pileChoice, discardCard, lastWriter, 0];
                    app.writeThingSpeak(fields, data)
            end
        end


        function writeThingSpeak(app, fields, values)
            app.WaitLamp.Color = "red";
            thingSpeakQueryTable =thingSpeakRead(app.channelID, 'ReadKey', app.readKey,...
                'Fields', 4, 'OutputFormat','table');
            lastWriteTime = thingSpeakQueryTable{1,1};
            timeSinceLast = datetime('now') - lastWriteTime;
            timeToPause = seconds(16) - timeSinceLast;
            pause(seconds(timeToPause))
            app.WaitLamp.Color = "green";
            thingSpeakWrite(app.channelID, 'WriteKey', app.writeKey,...
                'Fields', fields, 'Values', values);
        end

        function data = readThingSpeak(app, fields)
            data = thingSpeakRead(app.channelID, 'ReadKey', app.readKey,...
                'Fields', fields);

        end

        function win(app)
            [y, Fs] = audioread(fullfile(app.currentFilePath, "sounds", "winSound.wav"));
            player = audioplayer(y, Fs);
            play(player)
            app.winnerList = [app.winnerList; app.currentPlayerNum];
            listOfWinners = app.winnerList;
            save("winners","listOfWinners");
            winScreen;
            delete(app);
        end


        % Startup Helpers

        function offlineSetup(app)
            app.PlayerIndicatorLabel.Text = "Playing offline.";
            % Initialize Card Arrays
            app.drawPile = DrawPile(true);
            app.discardPile = CardArray([]);
            app.player1Hand = PlayerHand([], app.drawPile, app.discardPile);
            app.player2Hand = PlayerHand([], app.drawPile, app.discardPile);

            % Shuffle and Deal
            app.drawPile.Shuffle()
            app.drawPile.DealHands([app.player1Hand, app.player2Hand], app.discardPile);

        end

        function onlineSetup(app)
            app.OpponentLabel.Visible = "on";
            app.OpponentPile.Visible = "on";
            app.WaitLamp.Visible = "on";
            switch app.myPlayerNum
                case 1
                    app.player1Setup;
                case 2
                    app.player2Setup;
            end
            app.PlayerIndicatorLabel.Text = "You are Player " + app.myPlayerNum;
        end

        function player1Setup(app)
            app.offlineSetup;

            Discard1 = app.discardPile.topCard;
            lastWriter = 3;
            startHand = char(join(string(app.player2Hand.cards)));

            app.updateTopCard;
            app.updateHandDisplay;

            app.DrawPileButton.Enable = "off";
            app.DiscardPileButton.Enable = "off";
            app.writeThingSpeak([2,4,7],...
                {Discard1, lastWriter, startHand})
            app.DrawPileButton.Enable = "on";
            app.DiscardPileButton.Enable = "on";
        end

        function player2Setup(app)

            app.drawPile = DrawPile(false);
            app.discardPile = CardArray([]);
            app.player2Hand = PlayerHand([], app.drawPile, app.discardPile);


            app.TurnLabel.Text = "Waiting for Deal...";
            app.CurrentPhaseLabel.Text = "Wait for Player 1 to deal.";
            waitingForDeal = true;
            while waitingForDeal
                pause(2);
                data = thingSpeakRead(app.channelID, 'ReadKey', app.readKey,...
                    'Fields', [2,4,7], 'OutputFormat', 'table');
                Discard1 = data{1,2};
                lastWriter = data{1,3};
                startHand = data{1,4};
                if lastWriter == 3
                    waitingForDeal = false;
                end
            end

            app.player2Hand.cards = reshape(str2double(split(string(startHand))), 1, []);
            app.discardPile.cards = Discard1;

            app.updateHandDisplay;
            app.updateTopCard;

            % First turn is player 1's, so we switch "from player 2"
            app.currentPlayerNum = 2;
            app.nextTurn;
        end
    end


        %% Getter Functions
        methods

            function currentPlayerHand = get.currentPlayerHand(app)
                if app.online
                    switch app.myPlayerNum
                        case 1
                            currentPlayerHand = app.player1Hand;
                        case 2
                            currentPlayerHand = app.player2Hand;
                    end
                else
                    switch app.currentPlayerNum
                        case 1
                            currentPlayerHand = app.player1Hand;
                        case 2
                            currentPlayerHand = app.player2Hand;
                    end
                end
            end

            function selectedCardIndex = get.selectedCardIndex(app)
                depressedButtons = cell2mat(get(app.HandCardButtons, 'Value'));
                selectedCardIndex = find(depressedButtons);
            end


        %% BACKGROUNDS
        function updateBackground(app, noimage, image1, color)
            if noimage == true
                app.pauseMusic;
                app.backgroundImage.Enable = "off";
                app.backgroundImage.Visible = "off";
                app.RummyUIFigure.Color = color;
                app.Panel.BackgroundColor = color;
                app.currentTheme = "color";
            elseif image1 == true
                if app.currentTheme ~= "star wars"
                    app.StarWarsMusic
                end
                app.backgroundImage.ImageSource = fullfile(app.currentFilePath,'images','backgrounds','starWarsBackground.jpg');
                app.backgroundImage.Enable = "on";
                app.backgroundImage.Visible = "on";
                app.currentTheme = "star wars";
            else
                if app.currentTheme ~= "nature"
                    app.NatureMusic;
                end
                app.backgroundImage.ImageSource = fullfile(app.currentFilePath,'images','backgrounds','quadBackground.jpg');
                app.backgroundImage.Enable = "on";
                app.backgroundImage.Visible = "on";
                app.currentTheme = "nature";
                
            end
        end
        %% SOUNDS
        function [] = Click(app)
            [y, Fs] = audioread(fullfile(app.currentFilePath, "sounds", "clickSound.mp3"));
            player = audioplayer(3.5*y, Fs)
            play(player)
        end
        function [] = Shuffle(app)
            [y, Fs] = audioread(fullfile(app.currentFilePath, "sounds", "shuffleSound.mp3"));
            player = audioplayer(1.5*y, 1.5*Fs)
            play(player)
        end
        function [] = ShuffleShort(app)
            [y, Fs] = audioread(fullfile(app.currentFilePath, "sounds", "shuffleShortSound.wav"));
            player = audioplayer(1.7*y, Fs)
            play(player)
        end
        function [] = StarWarsMusic(app)
            pause(app.musicPlayer)
            [y, Fs] = audioread(fullfile(app.currentFilePath, "sounds", "starWarsCantina.mp3"));
            y = repmat(y, 12, 1);
            app.musicPlayer = audioplayer(y, Fs)
        end
        function [] = NatureMusic(app)
            pause(app.musicPlayer)
            [y, Fs] = audioread(fullfile(app.currentFilePath, "sounds", "natureSound.mp3"));
            y = repmat(y, 12, 1);
            app.musicPlayer = audioplayer(y, Fs)
        end

        function pauseMusic(app)
            pause(app.musicPlayer)
        end

        function playMusic(app)
            resume(app.musicPlayer)
        end

        %% Table
        function makeWinnersTable(app)
            winnersFile = fullfile(app.currentFilePath, "winners.mat");
            load(winnersFile, "listOfWinners")
            app.winnerList = listOfWinners;
            RoundsWonByPlayer1 = (app.winnerList == 1);
            RoundsWonByPlayer2 = (app.winnerList == 2);
            Player1Wins = sum(RoundsWonByPlayer1);
            Player2Wins = sum(RoundsWonByPlayer2);
            Player1Col = [num2cell(RoundsWonByPlayer1); {Player1Wins}];
            Player2Col = [num2cell(RoundsWonByPlayer2); {Player2Wins}];
            winnersTable = table(Player1Col, Player2Col);
            app.UITable.Data = winnersTable;
            roundsString = "Round " + string(1:length(app.winnerList));
            rowNames = [roundsString, "Total Wins"];
            app.UITable.RowName = rowNames;
            scroll(app.UITable, 'bottom');
        end
        
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Graphics Setup
            app.HandCardButtons = [app.Card1Button, app.Card2Button,...
                app.Card3Button, app.Card4Button, app.Card5Button,...
                app.Card6Button, app.Card7Button, app.Card8Button];

            set(app.HandCardButtons, "Text", "")

            %% Online/Offline Distinction
            settingsFile = fullfile(app.currentFilePath, "settings.mat");
            load(settingsFile);
            app.online = online;
            app.myPlayerNum = playerNum;

            app.updateBackground(noimage, image1, backgroundColor);
            if ~noimage
                if image1
                    app.StarWarsMusic
                else
                    app.NatureMusic
                end
            end

            if playingMusic
                app.playMusic
            end

            if app.online
                app.onlineSetup;
            else
                app.offlineSetup;
            end


            app.updateHandDisplay;

            app.updateTopCard;


            app.makeWinnersTable;


        end

        % Button pushed function: DrawPileButton
        function DrawPileButtonPushed(app, event)
            ShuffleShort(app)
            switch app.currentTurnPhase
                case "draw"
                    app.drawChoice = 0; %false, did not draw from discard
                    app.currentPlayerHand.DrawCard(false);
                    app.drawCardAnimation("draw")
                    app.nextPhase;
                otherwise
                    errordlg("It's not time to draw a card right now.")
            end
        end

        % Button pushed function: DiscardPileButton
        function DiscardPileButtonPushed(app, event)
            ShuffleShort(app)
            switch app.currentTurnPhase
                case "draw"
                    app.drawChoice = 1; %true did draw from discard
                    app.currentPlayerHand.DrawCard(true);
                    app.drawCardAnimation("discard")
                    app.nextPhase;
                case "discard"
                    % if isscalar(app.selectedCardIndex)
                    % app.currentPlayerHand.discard(app.selectedCardIndex);
                    % app.updateHandDisplay;
                    % app.updateTopCard;
                    % app.nextPhase;
                    % app.HandCardButtons(app.selectedCardIndex).Value = false;
                    % end
            end
        end

        % Button pushed function: DiscardButton
        function DiscardButtonPushed(app, event)
            ShuffleShort(app)
            app.DiscardButton.Enable = "off";
            switch app.currentTurnPhase
                case "discard"
                    if isscalar(app.selectedCardIndex)
                        index = app.selectedCardIndex;
                        app.HandCardButtons(index).BackgroundColor = [1 1 1];
                        app.HandCardButtons(index).Value = false;
                        app.discardChoice = app.currentPlayerHand.cards(index);
                        app.currentPlayerHand.discard(index);
                        app.discardAnimation(index)
                        app.nextPhase;
                    else
                        app.DiscardButton.Enable = "on";
                    end
                otherwise
                    errordlg("It's not time to discard a card right now.")
            end
        end

        % Button pushed function: SortByHandButton
        function SortByHandButtonPushed(app, event)
            Shuffle(app)
            app.currentPlayerHand.SortByRank;
            app.updateHandDisplay;
        end

        % Button pushed function: SortBySuitButton
        function SortBySuitButtonPushed(app, event)
            Shuffle(app)
            app.currentPlayerHand.SortBySuit;
            app.updateHandDisplay;
        end

        % Button pushed function: WinButton
        function WinButtonPushed(app, event)
            switch app.currentTurnPhase
                case "draw"
                    if app.online
                        app.writeThingSpeak([4,8], [app.myPlayerNum, 1])
                    end
                    app.win;
                otherwise
                    errordlg("You can only push that in the draw phase!")
            end
        end

        % Value changed function: Card1Button, Card2Button, Card3Button, 
        % ...and 5 other components
        function Card1ButtonValueChanged(app, event)
            selectedButton = event.Source;
            if selectedButton.Value
                selectedButton.BackgroundColor = [0.22, 0.71, 1];
                if length(app.selectedCardIndex) == 2
                    indices = app.selectedCardIndex(:);
                    set(app.HandCardButtons(indices), 'BackgroundColor', [1 1 1]);
                    set(app.HandCardButtons(indices), 'Value', false);
                    app.swapCardsAnimation(indices(1), indices(2));
                    app.currentPlayerHand.swapCards(indices)
                    app.updateHandDisplay;

                end
            else
                selectedButton.BackgroundColor = [1, 1, 1];
            end
        end

        % Button pushed function: guideButton
        function guideButtonPushed(app, event)
            Click(app)
            app.guide = guideApp(app);
            app.guideButton.Enable = "off";
        end

        % Button pushed function: QueryTurnButton
        function QueryTurnButtonPushed(app, event)
            lastWriter = app.readThingSpeak(4);
            % Query whether the other player
            switch app.myPlayerNum
                case 1
                    waiting = lastWriter ~= 2;
                case 2
                    waiting = lastWriter ~= 1;
            end
            if ~waiting
                app.readTurnData;
                app.nextTurn;
            end
        end

        % Button pushed function: settingsButton
        function settingsButtonPushed(app, event)
            Click(app)
            color = app.RummyUIFigure.Color;
            settingsApp(app, true, color);
            app.settingsButton.Enable = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.RummyUIFigure = uifigure('Visible', 'off');
            app.RummyUIFigure.Name = 'Rummy';
            app.RummyUIFigure.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'rumStack.png');
            app.backgroundImage = uiimage(app.RummyUIFigure);
            app.backgroundImage.Enable = 'off';
            app.backgroundImage.Visible = 'off';
            app.TurnLabel = uilabel(app.RummyUIFigure);
            app.TurnLabel.Text = 'Player 1''s Turn';
            app.DrawPileLabel = uilabel(app.RummyUIFigure);
            app.DrawPileLabel.Text = 'Draw Pile';
            app.DiscardPileLabel = uilabel(app.RummyUIFigure);
            app.DiscardPileLabel.Text = 'Discard Pile';
            app.DrawPileButton = uibutton(app.RummyUIFigure, 'push');
            app.DrawPileButton.ButtonPushedFcn = createCallbackFcn(app, @DrawPileButtonPushed, true);
            app.DrawPileButton.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardBack.png');
            app.DrawPileButton.Text = '';
            app.CurrentPhaseLabel = uilabel(app.RummyUIFigure);
            app.CurrentPhaseLabel.Text = 'Draw a Card from one of the piles.';
            app.DiscardButton = uibutton(app.RummyUIFigure, 'push');
            app.DiscardButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardButtonPushed, true);
            app.DiscardButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'discard_button.png');
            app.DiscardButton.Enable = 'off';
            app.DiscardButton.Text = '';
            app.SortByHandButton = uibutton(app.RummyUIFigure, 'push');
            app.SortByHandButton.ButtonPushedFcn = createCallbackFcn(app, @SortByHandButtonPushed, true);
            app.SortByHandButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'sort_by_hand.png');
            app.SortByHandButton.Text = '';
            app.SortBySuitButton = uibutton(app.RummyUIFigure, 'push');
            app.SortBySuitButton.ButtonPushedFcn = createCallbackFcn(app, @SortBySuitButtonPushed, true);
            app.SortBySuitButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'sort_by_suit.png');
            app.SortBySuitButton.Text = '';
            app.UITable = uitable(app.RummyUIFigure);
            app.UITable.ColumnName = {'Player 1'; 'Player 2'};
            app.UITable.RowName = {};
            app.WinButton = uibutton(app.RummyUIFigure, 'push');
            app.WinButton.ButtonPushedFcn = createCallbackFcn(app, @WinButtonPushed, true);
            app.WinButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'win_button.png');
            app.WinButton.Enable = 'off';
            app.WinButton.Text = '';
            app.Panel = uipanel(app.RummyUIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.Card4Button = uibutton(app.GridLayout, 'state');
            app.Card4Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card4Button.Tag = '4';
            app.Card4Button.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardPlacehold.png');
            app.Card4Button.Text = 'Card 4';
            app.Card2Button = uibutton(app.GridLayout, 'state');
            app.Card2Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card2Button.Tag = '2';
            app.Card2Button.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardPlacehold.png');
            app.Card2Button.Text = 'Card 2';
            app.Card3Button = uibutton(app.GridLayout, 'state');
            app.Card3Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card3Button.Tag = '3';
            app.Card3Button.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardPlacehold.png');
            app.Card3Button.Text = 'Card 3';
            app.Card1Button = uibutton(app.GridLayout, 'state');
            app.Card1Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card1Button.Tag = '1';
            app.Card1Button.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardPlacehold.png');
            app.Card1Button.Text = 'Card 1';
            app.Card5Button = uibutton(app.GridLayout, 'state');
            app.Card5Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card5Button.Tag = '5';
            app.Card5Button.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardPlacehold.png');
            app.Card5Button.Text = 'Card 5';
            app.Card6Button = uibutton(app.GridLayout, 'state');
            app.Card6Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card6Button.Tag = '6';
            app.Card6Button.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardPlacehold.png');
            app.Card6Button.Text = 'Card 6';
            app.Card7Button = uibutton(app.GridLayout, 'state');
            app.Card7Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card7Button.Tag = '7';
            app.Card7Button.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardPlacehold.png');
            app.Card7Button.Text = 'Card 7';
            app.Card8Button = uibutton(app.GridLayout, 'state');
            app.Card8Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card8Button.Tag = '8';
            app.Card8Button.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardPlacehold.png');
            app.Card8Button.Text = 'Card 8';
            app.DiscardPileButton = uibutton(app.RummyUIFigure, 'push');
            app.DiscardPileButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardPileButtonPushed, true);
            app.DiscardPileButton.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardPlacehold.png');
            app.DiscardPileButton.Text = '';
            app.LeaderboardTitle = uiimage(app.RummyUIFigure);
            app.LeaderboardTitle.ImageSource = fullfile(pathToMLAPP, 'images', 'misc', 'leaderboard.png');
            app.guideButton = uibutton(app.RummyUIFigure, 'push');
            app.guideButton.ButtonPushedFcn = createCallbackFcn(app, @guideButtonPushed, true);
            app.guideButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'guide.png');
            app.guideButton.Text = '';
            app.PlayerIndicatorLabel = uilabel(app.RummyUIFigure);
            app.PlayerIndicatorLabel.Text = 'Player Indicator';
            app.QueryTurnButton = uibutton(app.RummyUIFigure, 'push');
            app.QueryTurnButton.ButtonPushedFcn = createCallbackFcn(app, @QueryTurnButtonPushed, true);
            app.QueryTurnButton.Visible = 'off';
            app.QueryTurnButton.Text = 'Query Turn';
            app.WaitingtowriteThingspeakLabel = uilabel(app.RummyUIFigure);
            app.WaitingtowriteThingspeakLabel.Visible = 'off';
            app.WaitingtowriteThingspeakLabel.Text = 'Transmitting';
            app.WaitLamp = uilamp(app.RummyUIFigure);
            app.WaitLamp.Visible = 'off';
            app.AnimationCard = uiimage(app.RummyUIFigure);
            app.AnimationCard.ImageSource = fullfile(pathToMLAPP, 'images', 'cards', '2 CLUB.svg');
            app.settingsButton = uibutton(app.RummyUIFigure, 'push');
            app.settingsButton.ButtonPushedFcn = createCallbackFcn(app, @settingsButtonPushed, true);
            app.settingsButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'settingsButton.png');
            app.settingsButton.Text = '';
            app.AnimationCard_2 = uiimage(app.RummyUIFigure);
            app.AnimationCard_2.ImageSource = fullfile(pathToMLAPP, 'images', 'cards', '2 CLUB.svg');
            app.OpponentPile = uibutton(app.RummyUIFigure, 'push');
            app.OpponentPile.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardBack.png');
            app.OpponentPile.Visible = 'off';
            app.OpponentPile.Text = '';
            app.OpponentLabel = uilabel(app.RummyUIFigure);
            app.OpponentLabel.Visible = 'off';
            app.OpponentLabel.Text = 'Opponent';
            % Show the figure after all components are created
            app.RummyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rummy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RummyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RummyUIFigure)
        end
    end
end