classdef Rummy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RummyUIFigure      matlab.ui.Figure
        settingsButton     matlab.ui.control.Button
        DebugButton        matlab.ui.control.Button
        guideButton        matlab.ui.control.Button
        Image              matlab.ui.control.Image
        DiscardPileButton  matlab.ui.control.Button
        Panel              matlab.ui.container.Panel
        GridLayout         matlab.ui.container.GridLayout
        Card8Button        matlab.ui.control.StateButton
        Card7Button        matlab.ui.control.StateButton
        Card6Button        matlab.ui.control.StateButton
        Card5Button        matlab.ui.control.StateButton
        Card1Button        matlab.ui.control.StateButton
        Card3Button        matlab.ui.control.StateButton
        Card2Button        matlab.ui.control.StateButton
        Card4Button        matlab.ui.control.StateButton
        WinButton          matlab.ui.control.Button
        UITable            matlab.ui.control.Table
        SortBySuitButton   matlab.ui.control.Button
        SortByHandButton   matlab.ui.control.Button
        DiscardButton      matlab.ui.control.Button
        CurrentPhaseLabel  matlab.ui.control.Label
        DrawPileButton     matlab.ui.control.Button
        DiscardPileLabel   matlab.ui.control.Label
        DrawPileLabel      matlab.ui.control.Label
        TurnLabel          matlab.ui.control.Label
        backgroundImage    matlab.ui.control.Image
    end

    
    properties (Access = private)
        player1Hand;
        player2Hand;
        drawPile;
        discardPile;

        currentPlayerNum = 1;

        currentTurnPhase = "draw"

        winnerList;
        currentFilePath;
        cardFilePath;

        HandCardButtons;

        guide;


        channelID = 2570740
        readKey = "TYUSYKJEQ5E9E2VM"
        writeKey = "LP52VYH6OR502LC0"


    end

    properties (Access = private, Dependent)
        currentPlayerHand
        selectedCardIndex
    end

    methods (Access = private)

        function updateHandDisplay(app)
            cardsList = "images/cards/" + app.currentPlayerHand.filenames;
            if length(cardsList) == 7
                cardsList(8) = "images/misc/cardPlacehold.png";
            end
            cardsCell = num2cell(cardsList);
            % set(app.HandCardButtons, repmat({'Icon'},1,8), cardsCell)
            for i = 1:8
                app.HandCardButtons(i).Icon = cardsList(i);
            end

        end

        function nextPhase(app)
            currentPhase = app.currentTurnPhase;
            app.updateTopCard
            switch currentPhase
                case "draw"
                    % Switch to Discard Phase
                    app.currentTurnPhase = "discard";
                    app.updateTurnPhase("discard")
                case "discard"
                    % Switch to Draw Phafse
                    app.currentTurnPhase = "draw";
                    app.updateTurnPhase("draw")
                    
                    % It must be time to switch turns
                    app.nextTurn
            end
        end

        function updateTurnPhase(app, newPhase)
            switch newPhase
                case "draw"
                    app.CurrentPhaseLabel.Text = "Draw a Card from one of the piles.";
                    app.DrawPileButton.Enable = "on";
                    app.DiscardPileButton.Enable = "on";
                    app.DiscardButton.Enable = "off";
                case "discard"
                    app.CurrentPhaseLabel.Text = "Choose a card to discard.";
                    app.DrawPileButton.Enable = "off";
                    app.DiscardPileButton.Enable = "off";
                    app.WinButton.Enable = "off";
                    app.DiscardButton.Enable = "on";
            end
        end

        function nextTurn(app)
            prevPlayerNum = app.currentPlayerNum;
            switch prevPlayerNum
                case 1
                    app.currentPlayerNum = 2;
                case 2
                    app.currentPlayerNum = 1;
            end

            if checkRummyHand(app.currentPlayerHand.cards)
                app.WinButton.Enable = "on";
            end

            app.TurnLabel.Text = "Player " + app.currentPlayerNum...
                + "'s Turn";
            app.updateHandDisplay;
        end


        function updateTopCard(app)
            app.DiscardPileButton.Icon = "images/cards/" + app.discardPile.topCard;
        end
       
                function makeWinnersTable(app)
load("winners", "listOfWinners")
app.winnerList = listOfWinners;
RoundsWonByPlayer1 = (app.winnerList == 1);
RoundsWonByPlayer2 = (app.winnerList == 2);
Player1Wins = sum(RoundsWonByPlayer1);
Player2Wins = sum(RoundsWonByPlayer2);
Player1Col = [num2cell(RoundsWonByPlayer1); {Player1Wins}];
Player2Col = [num2cell(RoundsWonByPlayer2); {Player2Wins}];
winnersTable = table(Player1Col, Player2Col);
app.UITable.Data = winnersTable;
roundsString = "Round " + string(1:length(app.winnerList));
rowNames = [roundsString, "Total Wins"];
app.UITable.RowName = rowNames;
scroll(app.UITable, 'bottom');
end

    end

    methods

        function currentPlayerHand = get.currentPlayerHand(app)
            switch app.currentPlayerNum
                case 1
                    currentPlayerHand = app.player1Hand;
                case 2
                    currentPlayerHand = app.player2Hand;
            end
        end

        function selectedCardIndex = get.selectedCardIndex(app)
            depressedButtons = cell2mat(get(app.HandCardButtons, 'Value'));
            selectedCardIndex = find(depressedButtons);
        end
        
        function results = func9(app)
            
        end
    end
    %% UI CHANGES
methods (Access = public)
    %% BACKGROUNDS
    function updateBackground(app, noimage, image1, color)
        if noimage == true
            app.backgroundImage.Enable = "off";
            app.backgroundImage.Visible = "off";
            app.RummyUIFigure.Color = color;
        elseif image1 == true
                app.backgroundImage.ImageSource = fullfile('images/backgrounds/starWarsBackground.jpg');
                app.backgroundImage.Enable = "on";
                app.backgroundImage.Visible = "on";
        else
                app.backgroundImage.ImageSource = fullfile('images/backgrounds/quadBackground.jpg');
                app.backgroundImage.Enable = "on";
                app.backgroundImage.Visible = "on";
        end
    end
    %% SOUNDS
    function [] = Click(app)
            [y, Fs] = audioread("sounds/clickSound.mp3")
            player = audioplayer(3.5*y, Fs)
            play(player)
    end


   % function updateSound(app, soundValue)
    %    if soundValue == 1

      %  else
        %   pause(Click(app))
       % end
   % end


end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            currentFolder = fileparts(mfilename("fullpath"));
            addpath(currentFolder);


            app.drawPile = DrawPile(true);
            app.discardPile = CardArray([]);
            app.player1Hand = PlayerHand([], app.drawPile, app.discardPile);
            app.player2Hand = PlayerHand([], app.drawPile, app.discardPile);
            app.drawPile.Shuffle()
            app.drawPile.DealHands([app.player1Hand, app.player2Hand], app.discardPile);


            app.HandCardButtons = [app.Card1Button, app.Card2Button,...
                app.Card3Button, app.Card4Button, app.Card5Button,...
                app.Card6Button, app.Card7Button, app.Card8Button];

            set(app.HandCardButtons, "Text", "")
            
            currentFolder = fileparts(mfilename("fullpath"));
            addpath(currentFolder);


            app.updateHandDisplay;

            app.updateTopCard;


            app.makeWinnersTable;


        end

        % Button pushed function: DrawPileButton
        function DrawPileButtonPushed(app, event)
            switch app.currentTurnPhase
                case "draw"
                    app.currentPlayerHand.DrawCard(false);
                    app.updateHandDisplay;
                    app.nextPhase;
                otherwise
                    errordlg("It's not time to draw a card right now.")
            end
        end

        % Button pushed function: DiscardPileButton
        function DiscardPileButtonPushed(app, event)
            switch app.currentTurnPhase
                case "draw"
                    app.currentPlayerHand.DrawCard(true);
                    app.updateHandDisplay;
                    app.updateTopCard;
                    app.nextPhase;
                case "discard"
                    % if isscalar(app.selectedCardIndex)
                    % app.currentPlayerHand.discard(app.selectedCardIndex);
                    % app.updateHandDisplay;
                    % app.updateTopCard;
                    % app.nextPhase;
                    % app.HandCardButtons(app.selectedCardIndex).Value = false;
                    % end
            end
        end

        % Button pushed function: DiscardButton
        function DiscardButtonPushed(app, event)
            switch app.currentTurnPhase
                case "discard"
                    if isscalar(app.selectedCardIndex)
                    app.currentPlayerHand.discard(app.selectedCardIndex);
                    app.updateHandDisplay;
                    app.updateTopCard;
                    app.nextPhase;
                    app.HandCardButtons(app.selectedCardIndex).BackgroundColor = [1 1 1];
                    app.HandCardButtons(app.selectedCardIndex).Value = false;
                    end
                otherwise
                    errordlg("It's not time to discard a card right now.")
            end
        end

        % Button pushed function: SortByHandButton
        function SortByHandButtonPushed(app, event)
            app.currentPlayerHand.SortByRank;
            app.updateHandDisplay;
        end

        % Button pushed function: SortBySuitButton
        function SortBySuitButtonPushed(app, event)
            app.currentPlayerHand.SortBySuit;
            app.updateHandDisplay;
        end

        % Button pushed function: WinButton
        function WinButtonPushed(app, event)
            switch app.currentTurnPhase
                case "draw"
                    app.winnerList = [app.winnerList; app.currentPlayerNum];
                    listOfWinners = app.winnerList;
                    save("winners","listOfWinners");
                    startRummy("Congratulations, Player " + app.currentPlayerNum + "!");
                    delete(app);
                otherwise
                    errordlg("You can only push that in the draw phase!")
            end
        end

        % Value changed function: Card1Button, Card2Button, Card3Button, 
        % ...and 5 other components
        function Card1ButtonValueChanged(app, event)
            selectedButton = event.Source;
            if selectedButton.Value
                selectedButton.BackgroundColor = [0.22, 0.71, 1];
                if length(app.selectedCardIndex) == 2
                    app.currentPlayerHand.swapCards(app.selectedCardIndex)
                    app.updateHandDisplay;
                    set(app.HandCardButtons(app.selectedCardIndex), 'BackgroundColor', [1 1 1]);
                    set(app.HandCardButtons(app.selectedCardIndex), 'Value', false);
                end
            else
                selectedButton.BackgroundColor = [1, 1, 1];
            end
        end

        % Button pushed function: guideButton
        function guideButtonPushed(app, event)
           app.guide = guideApp(app);
           app.guideButton.Enable = "off";
        end

        % Callback function
        function AnimateButtonPushed(app, event)
            startPosition = app.DiscardPileButton.Position;
            endPosition = app.Card8Button.Position;
            for t = linspace(0,1,100)
                app.backgroundImage.Position = startPosition*(1-t) + endPosition * t;
                pause(0.1)
            end
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            app.drawPile
            app.discardPile
            app.player1Hand
            app.player2Hand
            app.currentPlayerHand
            handWinResult = checkRummyHand(app.currentPlayerHand)
        end

        % Button pushed function: settingsButton
        function settingsButtonPushed(app, event)
            Click(app)
            color = app.RummyUIFigure.Color;
            app.settings = settingsApp(app, color);
            app.settingsButton.Enable = "off";

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.RummyUIFigure = uifigure('Visible', 'off');
            app.RummyUIFigure.Name = 'Rummy';
            app.RummyUIFigure.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'rumStack.png');
            app.backgroundImage = uiimage(app.RummyUIFigure);
            app.backgroundImage.Enable = 'off';
            app.backgroundImage.Visible = 'off';
            app.TurnLabel = uilabel(app.RummyUIFigure);
            app.TurnLabel.Text = 'Player 1''s Turn';
            app.DrawPileLabel = uilabel(app.RummyUIFigure);
            app.DrawPileLabel.Text = 'Draw Pile';
            app.DiscardPileLabel = uilabel(app.RummyUIFigure);
            app.DiscardPileLabel.Text = 'Discard Pile';
            app.DrawPileButton = uibutton(app.RummyUIFigure, 'push');
            app.DrawPileButton.ButtonPushedFcn = createCallbackFcn(app, @DrawPileButtonPushed, true);
            app.DrawPileButton.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardBack.png');
            app.DrawPileButton.Text = '';
            app.CurrentPhaseLabel = uilabel(app.RummyUIFigure);
            app.CurrentPhaseLabel.Text = 'Draw a Card from one of the piles.';
            app.DiscardButton = uibutton(app.RummyUIFigure, 'push');
            app.DiscardButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardButtonPushed, true);
            app.DiscardButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'discard_button.png');
            app.DiscardButton.Enable = 'off';
            app.DiscardButton.Text = '';
            app.SortByHandButton = uibutton(app.RummyUIFigure, 'push');
            app.SortByHandButton.ButtonPushedFcn = createCallbackFcn(app, @SortByHandButtonPushed, true);
            app.SortByHandButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'sort_by_hand.png');
            app.SortByHandButton.Text = '';
            app.SortBySuitButton = uibutton(app.RummyUIFigure, 'push');
            app.SortBySuitButton.ButtonPushedFcn = createCallbackFcn(app, @SortBySuitButtonPushed, true);
            app.SortBySuitButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'sort_by_suit.png');
            app.SortBySuitButton.Text = '';
            app.UITable = uitable(app.RummyUIFigure);
            app.UITable.ColumnName = {'Player 1'; 'Player 2'};
            app.UITable.RowName = {};
            app.WinButton = uibutton(app.RummyUIFigure, 'push');
            app.WinButton.ButtonPushedFcn = createCallbackFcn(app, @WinButtonPushed, true);
            app.WinButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'win_button.png');
            app.WinButton.Enable = 'off';
            app.WinButton.Text = '';
            app.Panel = uipanel(app.RummyUIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.Card4Button = uibutton(app.GridLayout, 'state');
            app.Card4Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card4Button.Tag = '4';
            app.Card4Button.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardPlacehold.png');
            app.Card4Button.Text = 'Card 4';
            app.Card2Button = uibutton(app.GridLayout, 'state');
            app.Card2Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card2Button.Tag = '2';
            app.Card2Button.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardPlacehold.png');
            app.Card2Button.Text = 'Card 2';
            app.Card3Button = uibutton(app.GridLayout, 'state');
            app.Card3Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card3Button.Tag = '3';
            app.Card3Button.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardPlacehold.png');
            app.Card3Button.Text = 'Card 3';
            app.Card1Button = uibutton(app.GridLayout, 'state');
            app.Card1Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card1Button.Tag = '1';
            app.Card1Button.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardPlacehold.png');
            app.Card1Button.Text = 'Card 1';
            app.Card5Button = uibutton(app.GridLayout, 'state');
            app.Card5Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card5Button.Tag = '5';
            app.Card5Button.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardPlacehold.png');
            app.Card5Button.Text = 'Card 5';
            app.Card6Button = uibutton(app.GridLayout, 'state');
            app.Card6Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card6Button.Tag = '6';
            app.Card6Button.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardPlacehold.png');
            app.Card6Button.Text = 'Card 6';
            app.Card7Button = uibutton(app.GridLayout, 'state');
            app.Card7Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card7Button.Tag = '7';
            app.Card7Button.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardPlacehold.png');
            app.Card7Button.Text = 'Card 7';
            app.Card8Button = uibutton(app.GridLayout, 'state');
            app.Card8Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card8Button.Tag = '8';
            app.Card8Button.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardPlacehold.png');
            app.Card8Button.Text = 'Card 8';
            app.DiscardPileButton = uibutton(app.RummyUIFigure, 'push');
            app.DiscardPileButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardPileButtonPushed, true);
            app.DiscardPileButton.Icon = fullfile(pathToMLAPP, 'images', 'misc', 'cardPlacehold.png');
            app.DiscardPileButton.Text = '';
            app.Image = uiimage(app.RummyUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'misc', 'leaderboard.png');
            app.guideButton = uibutton(app.RummyUIFigure, 'push');
            app.guideButton.ButtonPushedFcn = createCallbackFcn(app, @guideButtonPushed, true);
            app.guideButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'guide.png');
            app.guideButton.Text = '';
            app.DebugButton = uibutton(app.RummyUIFigure, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = 'Debug';
            app.settingsButton = uibutton(app.RummyUIFigure, 'push');
            app.settingsButton.ButtonPushedFcn = createCallbackFcn(app, @settingsButtonPushed, true);
            app.settingsButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'settingsButton.png');
            app.settingsButton.Text = '';
            % Show the figure after all components are created
            app.RummyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rummy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RummyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RummyUIFigure)
        end
    end
end