classdef buttontest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        titleGraphic    matlab.ui.control.Image
        credits         matlab.ui.control.Image
        settingsButton  matlab.ui.control.Button
        playButton      matlab.ui.control.Button
        guideButton     matlab.ui.control.Button
    end

    methods (Access = private)
        function startupFcn(app)
app.credits.Visible = "on";
app.playButton.Visible = "on";
app.settingsButton.Visible = "on";
app.guideButton.Visible = "on";
app.titleGraphic.Visible = "on";


% Turn the other stuff off
        end
    end

    properties (Access = private)
        guideApp % Reference to guide app
        settingsApp % Reference to settings app
    end
    
    %% SOUNDS
    methods (Access = public)
        
        function [] = Click(app)
            [y, Fs] = audioread("CLICK.mp3")
            player = audioplayer(3.5*y, Fs)
            play(player)
        end
        
        function [] = losingSound(app)
            [y, Fs] = audioread("womp_pipe.mp3")
            player = audioplayer(1.25*y, Fs)
            play(player)
        end
        
        function [] = winSound(app)
            [y, Fs] = audioread("WINWIN.wav")
            player = audioplayer(y, Fs)
            play(player)
        end
      %  function [] = cardDealingSound(app)
       %     [y, Fs] = audioread("carDealing.mp3")
         %   player = audioplayer(y, Fs)
         %   play(player)
        % end

    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: guideButton
        function guideButtonPushed(app, event)
           app.guideApp = guideApp(app);
           app.guideButton.Enable = "off";
        
           Click(app)
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
           % Turns off title screen
           app.credits.Visible = "off";
           app.playButton.Visible = "off";
           app.settingsButton.Visible = "off";
           app.guideButton.Visible = "off";
           app.titleGraphic.Visible = "off";
           % Add visibility of actual game
            
            Click(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.guideApp);
            delete(app.settingsApp);
            delete(app);
        end

        % Button pushed function: settingsButton
        function settingsButtonPushed(app, event)
            background = 2;
            music = 1;
            app.settingsButton.Enable = "off";
            app.settingsApp = settingsApp(app, background, music);
            Click(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.guideButton = uibutton(app.UIFigure, 'push');
            app.guideButton.ButtonPushedFcn = createCallbackFcn(app, @guideButtonPushed, true);
            app.guideButton.Icon = fullfile(pathToMLAPP, 'guide.png');
            app.guideButton.Text = '';
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Icon = fullfile(pathToMLAPP, 'startButton.png');
            app.playButton.Text = '';
            app.settingsButton = uibutton(app.UIFigure, 'push');
            app.settingsButton.ButtonPushedFcn = createCallbackFcn(app, @settingsButtonPushed, true);
            app.settingsButton.Icon = fullfile(pathToMLAPP, 'settingsButton.png');
            app.settingsButton.Text = '';
            app.credits = uiimage(app.UIFigure);
            app.credits.ImageSource = fullfile(pathToMLAPP, 'credit.png');
            app.titleGraphic = uiimage(app.UIFigure);
            app.titleGraphic.ImageSource = fullfile(pathToMLAPP, 'RumTitle.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = buttontest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end