classdef winScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        NewGameButton         matlab.ui.control.Button
        MainMenuButton        matlab.ui.control.Button
        CongratulationsLabel  matlab.ui.control.Label
        UITable               matlab.ui.control.Table
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        winnerList % Description
        currentFilePath = fileparts(mfilename("fullpath"));
    end
    
    methods (Access = private)
        
        function makeWinnersGraph(app)
            load("winners", "listOfWinners")
            winnerList = listOfWinners;
            numWinners = length(winnerList);
            RoundsWonByPlayer1 = (app.winnerList == 1);
            RoundsWonByPlayer2 = (app.winnerList == 2);
            summerMatrix = tril(ones(numWinners));
            player1Cumulative = summerMatrix * RoundsWonByPlayer1;
            player2Cumulative = summerMatrix * RoundsWonByPlayer2;
            xValues = 1:numWinners;

            mostWinners = max(sum(RoundsWonByPlayer1), sum(RoundsWonByPlayer2));

            plot(app.UIAxes, xValues, player1Cumulative, "*-", xValues, player2Cumulative, "*-");
            legend(app.UIAxes, "Player 1", "Player 2", 'Location', 'northwest')
            xticks(app.UIAxes, 0:numWinners);
            yticks(app.UIAxes, 0:mostWinners);
        end
        
        function makeWinnersTable(app)
load("winners", "listOfWinners")
app.winnerList = listOfWinners;
RoundsWonByPlayer1 = (app.winnerList == 1);
RoundsWonByPlayer2 = (app.winnerList == 2);
Player1Wins = sum(RoundsWonByPlayer1);
Player2Wins = sum(RoundsWonByPlayer2);
Player1Col = [num2cell(RoundsWonByPlayer1); {Player1Wins}];
Player2Col = [num2cell(RoundsWonByPlayer2); {Player2Wins}];
winnersTable = table(Player1Col, Player2Col);
app.UITable.Data = winnersTable;
roundsString = "Round " + string(1:length(app.winnerList));
rowNames = [roundsString, "Total Wins"];
app.UITable.RowName = rowNames;
scroll(app.UITable, 'bottom');
end
    end
   %% SOUNDS 
    methods (Access = public)
        
        function [] = Click(app)
            [y, Fs] = audioread(fullfile(app.currentFilePath, "sounds", "clickSound.mp3"))
            player = audioplayer(3.5*y, Fs)
            play(player)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.makeWinnersTable;
            app.makeWinnersGraph;
            app.CongratulationsLabel.Text = "Congratulations, Player " + ...
                app.winnerList(end) + "!";
        end

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            Click(app)
            startRummy();
            delete(app);
        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
            Click(app)
            Rummy();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Player Wins over Time')
            xlabel(app.UIAxes, 'Round')
            ylabel(app.UIAxes, 'Wins')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Player 1'; 'Player 2'};
            app.UITable.RowName = {};
            app.CongratulationsLabel = uilabel(app.UIFigure);
            app.CongratulationsLabel.Text = 'Congratulations';
            app.MainMenuButton = uibutton(app.UIFigure, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'mainMenuButton.png');
            app.MainMenuButton.Text = '';
            app.NewGameButton = uibutton(app.UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'newGameButton.png');
            app.NewGameButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winScreen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end